classdef PCMgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FsSpinner                      matlab.ui.control.Spinner
        FsSpinnerLabel                 matlab.ui.control.Label
        LevelsSpinner                  matlab.ui.control.Spinner
        LevelsSpinnerLabel             matlab.ui.control.Label
        PCMGUIusingvoiceinputwithcompandingLabel  matlab.ui.control.Label
        ProfPSMahajaniLabel            matlab.ui.control.Label
        UndertheguidanceofLabel        matlab.ui.control.Label
        PC21LopamudraParashtekarLabel  matlab.ui.control.Label
        PC16RohilNatarajLabel          matlab.ui.control.Label
        PC15RohanHabuLabel             matlab.ui.control.Label
        GroupmembersLabel              matlab.ui.control.Label
        TYBTECHLabel                   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        CompandingcurveCheckBox        matlab.ui.control.CheckBox
        LawCoefficientKnob             matlab.ui.control.Knob
        LawCoefficientKnobLabel        matlab.ui.control.Label
        LawListBox                     matlab.ui.control.ListBox
        LawListBoxLabel                matlab.ui.control.Label
        ModeListBox                    matlab.ui.control.ListBox
        ModeListBoxLabel               matlab.ui.control.Label
        DisplayResultsButton           matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        StopRecordingButton            matlab.ui.control.Button
        StartRecordingButton           matlab.ui.control.Button
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentTime % Description
        bits % Description
        Fs % Description
        channels % Description
        id % Description
        recording
        recordedData % Description
        file % Description
        waveForm % Description
        fileDuration % Description
        fileInfo % Description
        samplingTime % Description
        sampledSignal % Description
        quantizedSignal % Description
        PCMlevels % Description
        vmax % Description
        vmin % Description
        del % Description
        part % Description
        code % Description
        index % Description
        compressedSignal % Description
        expandedSignal % Description
        Alawcoeff % Description
        muLawcoeff % Description
        compandingCurveRequired % Description
        l1 % Description
        l2 % Description
        coded % Description
        qunt % Description
        q % Description
    end
    
    methods (Access = private)
        
        function plotCompandingCurve(app)
            switch(app.compandingCurveRequired)                    
                case 1
                    figure(2);
                    disp(length(app.waveForm));
                    disp(length(app.compressedSignal));
                    disp(length(app.expandedSignal));
                    plot(app.waveForm,app.compressedSignal);
                    hold on;
                    plot(app.compressedSignal,app.expandedSignal);
                    hold off;
            end
        end
        
        function sampling(app)
            app.samplingTime=linspace(0,app.fileDuration,app.fileDuration);
            disp(length(app.samplingTime));
            app.sampledSignal=stem(app.UIAxes2,app.samplingTime,app.waveForm);
            disp(app.sampledSignal);
            xlim(app.UIAxes2,[0 app.fileDuration]);
        end
        
        function qunatization(app)
            peaks=findpeaks(app.waveForm);
% %             disp("peaks are: ");
            app.vmax=max(peaks);
            app.vmin=-app.vmax;
            disp("vmax: ");
            disp(app.vmax);
            disp("vmin: ");
            disp(app.vmin);
%             disp(peaks);
            app.PCMlevels=2^app.bits;
            app.del=(app.vmax-app.vmin)/app.PCMlevels;
            app.part=app.vmin:app.del:app.vmax;
            fprintf("Partition:");
            disp(app.part);
            app.code=app.vmin-(app.del/2):app.del:app.vmax-(app.del/2);
            app.code=[app.code,0];
            fprintf("Codebook:");
            disp(app.code);
%             disp(app.part);
%             waveform=round(app.waveForm);
            [app.index,app.quantizedSignal]=quantiz(app.waveForm,app.part,app.code);
            fprintf("Index:")
%             disp(app.index);
            disp(length(app.index));
            fprintf("qauantized values");
%             disp(app.quantizedSignal);
            disp(length(app.quantizedSignal));
            
            app.l1=length(app.index);
            app.l2=length(app.quantizedSignal);
            for i=1:app.l1
                if(app.index(i)~=0)
                    app.index(i)=app.index(i)-1;
                end
            end
            for i=1:app.l2
                if(app.quantizedSignal(i)==app.vmin-(app.del/2))
                    app.quantizedSignal(i)=app.vmin+(app.del/2);
                end
            end
            stem(app.UIAxes3,app.samplingTime,app.quantizedSignal)
        end
        
        function encoding(app)
            app.code=de2bi(app.index,'left-msb');
            k=1;
            for i=1:app.l1
                for j=1:app.bits
                    app.coded(k)=app.code(i,j);
                    k=k+1;
                end
            end
            stairs(app.UIAxes4,app.coded);
            xlim(app.UIAxes4,[0 app.Fs*app.bits]);
        end
        
        function decoding(app)
            app.qunt=reshape(app.coded,app.bits,length(app.coded)/app.bits);
            plot(app.UIAxes5,app.samplingTime,app.qunt);
            app.index=bi2de(app.qunt','left-msb');
            app.q=app.del*app.index+(app.del/2)+app.vmin;
            plot(app.UIAxes6,app.samplingTime,app.q);
        end
        
        function Acompressing(app)
            app.UIAxes7.Visible=1;
            app.compressedSignal=compand(app.waveForm,app.Alawcoeff,app.vmax,'A/compressor');
            plot(app.UIAxes7,app.compressedSignal);
        end
        
        function muCompressing(app)
            app.UIAxes7.Visible=1;
            app.compressedSignal=compand(app.waveForm,app.muLawcoeff,app.vmax,'mu/compressor');
            plot(app.UIAxes7,app.compressedSignal);
        end
        
        function Aexpanding(app)
            app.UIAxes8.Visible=1;
            app.expandedSignal=compand(app.q,app.Alawcoeff,app.vmax,'A/expander');
            plot(app.UIAxes8,app.samplingTime,app.expandedSignal);
        end
        
        function muExpanding(app)
            app.UIAxes8.Visible=1;
            app.expandedSignal=compand(app.q,app.muLawcoeff,app.vmax,'mu/expander');
            plot(app.UIAxes8,app.samplingTime,app.expandedSignal);
        end
        
        function reconstruct(app)
            app.index=bi2de(app.qunt','left-msb');
            app.q=app.del*app.index+(app.del/2)+app.vmin;
            plot(app.UIAxes6,app.samplingTime,app.q);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            app.Alawcoeff=87.6;
            app.muLawcoeff=255;
            app.compandingCurveRequired=0;
            app.Fs=8000;
            app.bits=8;
            app.channels=2;
            app.id=-1;
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.StartRecordingButton.Enable=0;
            app.StopRecordingButton.Enable=1;
            app.DisplayResultsButton.Enable=0;
            app.ModeListBox.Enable=0;
%             app.Fs=8000;
%             app.bits=8;
%             app.channels=2;
%             app.id=-1;
            app.Label.Text="Recording started";
            app.Label.BackgroundColor="red";
            app.recording=audiorecorder(app.Fs,app.bits,app.channels,app.id);
            record(app.recording);
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            app.StopRecordingButton.Enable=0;
            app.StartRecordingButton.Enable=1;
            app.DisplayResultsButton.Enable=1;
            app.ModeListBox.Enable=1;
            if(app.Label.BackgroundColor==[1,0,0])
            stop(app.recording);
            app.ModeListBox.Enable=1;
            app.recordedData=getaudiodata(app.recording);
            app.file=["A:\mitwpu\ty\tri 1\Digital Communication\Grp Project\newfile.wav"];
            app.Label.Text="Recording stopped";
            app.Label.BackgroundColor="Green";
            audiowrite(app.file,app.recordedData,app.Fs);
%             plotting the recorded signal
            app.fileInfo=audioinfo(app.file);
            disp(app.fileInfo);
            app.fileDuration=app.fileInfo.TotalSamples;
            disp(app.fileDuration);
%             app.fileDuration=round(app.fileDuration);
            disp(app.fileDuration);
            [app.waveForm,app.Fs]=audioread(app.file);
            app.waveForm=app.waveForm(:,1);
%             app.UIAxes(app.waveForm);
            plot(app.UIAxes, app.waveForm);
            xlim(app.UIAxes,[0 app.fileDuration]);
%             set(app.UIAxes,)
            disp(length(app.waveForm));
            else
                app.Label.Text="Recording already exists.";
                pause(2);
                app.Label.Text="Please start recording again.";
                pause(2);
                app.Label.Text="Recording stopped";
            end
        end

        % Button pushed function: DisplayResultsButton
        function DisplayResultsButtonPushed(app, event)
% check which type of PCM is used
            type=app.ModeListBox.Value;
            switch type
                case 'Linear PCM'
%             plotting sampled signal
                    app.sampling();
%             plotting quantized signal
                    app.qunatization();
%             plotting the encoded signal
                    app.encoding()
%             plotting the decoded signal
                    app.decoding()
                case 'Companding PCM'
                    whichLaw=app.LawListBox.Value;
                    switch whichLaw
                        case 'A law'
%             plotting the sampled signal
                            app.sampling();
%             compressing the signal
                            app.Acompressing();
%             plotting quantized signal
                            app.qunatization();
%             plotting the encoded signal
                            app.encoding();
%             plotting the decoded signal
                            app.decoding();
%             reconstructing the signal
                            app.reconstruct();
            
%             expanding the signal
                            app.Aexpanding();
%             plotting companding curve
                            app.plotCompandingCurve();
                        case 'mu law'
%             plotting the sampled signal
                            app.sampling();
%             compressing the signal
                            app.muCompressing()
%             plotting quantized signal
                            app.qunatization();
%             plotting the encoded signal
                            app.encoding();
%             plotting the decoded signal
                            app.decoding();
%             expanding the signal
                            app.muExpanding();
%             plotting companding curve
                            app.plotCompandingCurve();
                    end
            end


%             %             plotting the sampled signal
%             app.samplingTime=linspace(0,app.fileDuration,app.fileDuration);
%             disp(length(app.samplingTime));
%             app.sampledSignal=stem(app.UIAxes2,app.samplingTime,app.waveForm);
%             disp(app.sampledSignal);
%             xlim(app.UIAxes2,[0 app.fileDuration]);
% %             plotting quantized signal
%             peaks=findpeaks(app.waveForm);
% % %             disp("peaks are: ");
%             app.vmax=max(peaks);
%             app.vmin=-app.vmax;
%             disp("vmax: ");
%             disp(app.vmax);
%             disp("vmin: ");
%             disp(app.vmin);
% %             disp(peaks);
%             app.PCMlevels=2^app.bits;
%             app.del=(app.vmax-app.vmin)/app.PCMlevels;
%             app.part=app.vmin:app.del:app.vmax;
%             fprintf("Partition:");
%             disp(app.part);
%             app.code=app.vmin-(app.del/2):app.del:app.vmax-(app.del/2);
%             app.code=[app.code,0];
%             fprintf("Codebook:");
%             disp(app.code);
% %             disp(app.part);
% %             waveform=round(app.waveForm);
%             [app.index,app.quantizedSignal]=quantiz(app.waveForm,app.part,app.code);
%             fprintf("Index:")
% %             disp(app.index);
%             disp(length(app.index));
%             fprintf("qauantized values");
% %             disp(app.quantizedSignal);
%             disp(length(app.quantizedSignal));
%             
%             l1=length(app.index);
%             l2=length(app.quantizedSignal);
%             for i=1:l1
%                 if(app.index(i)~=0)
%                     app.index(i)=app.index(i)-1;
%                 end
%             end
%             for i=1:l2
%                 if(app.quantizedSignal(i)==app.vmin-(app.del/2))
%                     app.quantizedSignal(i)=app.vmin+(app.del/2);
%                 end
%             end
%             stem(app.UIAxes3,app.samplingTime,app.quantizedSignal)
%             
% %             plotting the encoded signal
%             app.code=de2bi(app.index,'left-msb');
%             k=1;
%             for i=1:l1
%                 for j=1:app.bits
%                     coded(k)=app.code(i,j);
%                     k=k+1;
%                 end
%             end
%             stairs(app.UIAxes4,coded);
%             xlim(app.UIAxes4,[0 app.Fs*app.bits]);
%             
% %             plotting the decoded signal
%             qunt=reshape(coded,app.bits,length(coded)/app.bits);
%             plot(app.UIAxes5,app.samplingTime,qunt);
%             app.index=bi2de(qunt','left-msb');
%             q=app.del*app.index+(app.del/2)+app.vmin;
%             plot(app.UIAxes6,app.samplingTime,q);


% companding code
% compressing the signal
% app.UIAxes7.Visible=1;
% app.compressedSignal=compand(app.waveForm,87.6,app.vmax,'A/compressor');
% plot(app.UIAxes7,app.samplingTime,app.compressedSignal);
% 
% % expanding the signal
% app.UIAxes8.Visible=1;
% app.expandedSignal=compand(app.compressedSignal,87.6,app.vmax,'A/expander');
% plot(app.UIAxes8,app.samplingTime,app.expandedSignal);
        end

        % Value changed function: ModeListBox
        function ModeListBoxValueChanged(app, event)
            value = app.ModeListBox.Value;
            switch(value)
                case 'Linear PCM'
                    app.LawListBox.Enable=0;
                    app.LawCoefficientKnobLabel.Enable=0;
                    app.LawCoefficientKnob.Enable=0;
                    app.CompandingcurveCheckBox.Enable=0;
                    app.UIAxes7.Visible=0;
                    cla(app.UIAxes7);
                    app.UIAxes8.Visible=0;
                    cla(app.UIAxes8);
                    app.Image.Visible=1;
                    app.GroupmembersLabel.Visible=1;
                    app.PC15RohanHabuLabel.Visible=1;
                    app.PC16RohilNatarajLabel.Visible=1;
                    app.PC21LopamudraParashtekarLabel.Visible=1;
                    app.UndertheguidanceofLabel.Visible=1;
                    app.ProfPSMahajaniLabel.Visible=1;
                    app.PCMGUIusingvoiceinputwithcompandingLabel.Visible=1;
                    app.TYBTECHLabel.Visible=1;
                case 'Companding PCM'
                    app.LawListBox.Enable=1;
%                     app.LawCoefficientKnobLabel.Enable=1;
%                     app.LawCoefficientKnob.Enable=1;
                    app.CompandingcurveCheckBox.Enable=1;
                    app.UIAxes7.Visible=1;
                    app.UIAxes8.Visible=1;
                    app.Image.Visible=0;
                    app.GroupmembersLabel.Visible=0;
                    app.PC15RohanHabuLabel.Visible=0;
                    app.PC16RohilNatarajLabel.Visible=0;
                    app.PC21LopamudraParashtekarLabel.Visible=0;
                    app.UndertheguidanceofLabel.Visible=0;
                    app.ProfPSMahajaniLabel.Visible=0;
                    app.PCMGUIusingvoiceinputwithcompandingLabel.Visible=0;
                    app.TYBTECHLabel.Visible=0;
            end
        end

        % Value changing function: LawCoefficientKnob
        function LawCoefficientKnobValueChanging(app, event)
            changingValue = event.Value;
            switch(app.LawListBox.Value)
                case 'A law'
                    app.Alawcoeff=changingValue;
                case 'mu law'
                    app.muLawcoeff=changingValue;
            end
        end

        % Value changed function: CompandingcurveCheckBox
        function CompandingcurveCheckBoxValueChanged(app, event)
            value = app.CompandingcurveCheckBox.Value;
            app.compandingCurveRequired=value;
            if value==1
            app.LawCoefficientKnob.Enable=1;
            app.LawCoefficientKnobLabel.Enable=1;
            else
                app.LawCoefficientKnob.Enable=0;
                app.LawCoefficientKnobLabel.Enable=0;
            end
        end

        % Value changed function: LevelsSpinner
        function LevelsSpinnerValueChanged(app, event)
            value = app.LevelsSpinner.Value;
            app.PCMlevels=value;
            app.bits=log2(app.PCMlevels)
%             app.bits=round(app.bits)
            disp(app.PCMlevels)
            disp(app.bits)
        end

        % Value changed function: FsSpinner
        function FsSpinnerValueChanged(app, event)
            value = app.FsSpinner.Value;
            app.Fs=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sampled Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Quantized Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Encoded Signal')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Decoded Signal')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Reconstructed Signal')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Compressed Waveform')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.Visible = 'off';
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Expanded Waveform')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.Visible = 'off';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Text = 'Stop Recording';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DisplayResultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultsButtonPushed, true);
            app.DisplayResultsButton.Enable = 'off';
            app.DisplayResultsButton.Text = 'Display Results';
            app.ModeListBoxLabel = uilabel(app.UIFigure);
            app.ModeListBoxLabel.Enable = 'off';
            app.ModeListBoxLabel.Text = 'Mode';
            app.ModeListBox = uilistbox(app.UIFigure);
            app.ModeListBox.Items = {'Linear PCM', 'Companding PCM'};
            app.ModeListBox.ValueChangedFcn = createCallbackFcn(app, @ModeListBoxValueChanged, true);
            app.ModeListBox.Enable = 'off';
            app.ModeListBox.Value = 'Linear PCM';
            app.LawListBoxLabel = uilabel(app.UIFigure);
            app.LawListBoxLabel.Enable = 'off';
            app.LawListBoxLabel.Text = 'Law';
            app.LawListBox = uilistbox(app.UIFigure);
            app.LawListBox.Items = {'A law', 'mu law'};
            app.LawListBox.Enable = 'off';
            app.LawListBox.Value = 'A law';
            app.LawCoefficientKnobLabel = uilabel(app.UIFigure);
            app.LawCoefficientKnobLabel.Enable = 'off';
            app.LawCoefficientKnobLabel.Text = 'Law Coefficient';
            app.LawCoefficientKnob = uiknob(app.UIFigure, 'continuous');
            app.LawCoefficientKnob.Limits = [1 255];
            app.LawCoefficientKnob.MajorTicks = [1 43.8 87.6 127.5 167.4 211.2 255];
            app.LawCoefficientKnob.ValueChangingFcn = createCallbackFcn(app, @LawCoefficientKnobValueChanging, true);
            app.LawCoefficientKnob.MinorTicks = [1 8 16 24 32 40 48 56 64 72 80 88 96 104 112 120 128 136 144 152 160 168 176 184 192 200 208 216 224 232 240 248 255];
            app.LawCoefficientKnob.Enable = 'off';
            app.LawCoefficientKnob.Value = 1;
            app.CompandingcurveCheckBox = uicheckbox(app.UIFigure);
            app.CompandingcurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompandingcurveCheckBoxValueChanged, true);
            app.CompandingcurveCheckBox.Enable = 'off';
            app.CompandingcurveCheckBox.Text = 'Companding curve';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'mit logo.png';
            app.TYBTECHLabel = uilabel(app.UIFigure);
            app.TYBTECHLabel.Text = 'TYBTECH';
            app.GroupmembersLabel = uilabel(app.UIFigure);
            app.GroupmembersLabel.Text = 'Group members:';
            app.PC15RohanHabuLabel = uilabel(app.UIFigure);
            app.PC15RohanHabuLabel.Text = 'PC15 Rohan Habu';
            app.PC16RohilNatarajLabel = uilabel(app.UIFigure);
            app.PC16RohilNatarajLabel.Text = 'PC16 Rohil Nataraj';
            app.PC21LopamudraParashtekarLabel = uilabel(app.UIFigure);
            app.PC21LopamudraParashtekarLabel.Text = 'PC21 Lopamudra Parashtekar';
            app.UndertheguidanceofLabel = uilabel(app.UIFigure);
            app.UndertheguidanceofLabel.Text = 'Under the guidance of';
            app.ProfPSMahajaniLabel = uilabel(app.UIFigure);
            app.ProfPSMahajaniLabel.Text = 'Prof.P.S.Mahajani';
            app.PCMGUIusingvoiceinputwithcompandingLabel = uilabel(app.UIFigure);
            app.PCMGUIusingvoiceinputwithcompandingLabel.Text = 'PCM GUI using voice input with companding';
            app.LevelsSpinnerLabel = uilabel(app.UIFigure);
            app.LevelsSpinnerLabel.Text = 'Levels';
            app.LevelsSpinner = uispinner(app.UIFigure);
            app.LevelsSpinner.Limits = [64 2048];
            app.LevelsSpinner.ValueChangedFcn = createCallbackFcn(app, @LevelsSpinnerValueChanged, true);
            app.LevelsSpinner.Value = 64;
            app.FsSpinnerLabel = uilabel(app.UIFigure);
            app.FsSpinnerLabel.Text = 'Fs';
            app.FsSpinner = uispinner(app.UIFigure);
            app.FsSpinner.Limits = [81 44100];
            app.FsSpinner.ValueChangedFcn = createCallbackFcn(app, @FsSpinnerValueChanged, true);
            app.FsSpinner.Value = 81;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PCMgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end